classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        TestButton              matlab.ui.control.Button
        ResumeButton            matlab.ui.control.Button
        RunSimulationButton     matlab.ui.control.Button
        StatusLamp              matlab.ui.control.Lamp
        StatusLampLabel         matlab.ui.control.Label
        SpawnRobotButton        matlab.ui.control.Button
        SpawnEnvironmentButton  matlab.ui.control.Button
        EStopButton             matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        TeachLabel              matlab.ui.control.Label
        ZButton_2               matlab.ui.control.Button
        ZButton                 matlab.ui.control.Button
        XButton                 matlab.ui.control.Button
        XButton_2               matlab.ui.control.Button
        YButton_2               matlab.ui.control.Button
        YButton                 matlab.ui.control.Button
        q7Slider                matlab.ui.control.Slider
        q7SliderLabel           matlab.ui.control.Label
        q6Slider                matlab.ui.control.Slider
        q6SliderLabel           matlab.ui.control.Label
        q4Slider                matlab.ui.control.Slider
        q4SliderLabel           matlab.ui.control.Label
        q5Slider                matlab.ui.control.Slider
        q5SliderLabel           matlab.ui.control.Label
        q3Slider                matlab.ui.control.Slider
        q3SliderLabel           matlab.ui.control.Label
        q2Slider                matlab.ui.control.Slider
        q2SliderLabel           matlab.ui.control.Label
        q1Slider                matlab.ui.control.Slider
        q1SliderLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        r;
        q;
        prevQ;
        sim
        resumeStatus;
    end
    
    methods (Access = public)
        
        function spawn(app)
            app.r = LinearUR5();
            app.r.model;
        end

        function animate(app)
            if (getEstopStatus == false)
                jTrajQMatrix = jtraj(app.prevQ,app.q,50);
                for i = 1:size(jTrajQMatrix, 1)
                    app.r.model.animate(jTrajQMatrix(i,:)); % animating the joint limits to move robot
                    drawnow();
                end
                app.prevQ = app.q;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.q = [0 0 0 0 0 0 0];
            app.prevQ = [0 0 0 0 0 0 0];
            setEstopStatus(false);
            app.resumeStatus = true;
        end

        % Button pushed function: SpawnEnvironmentButton
        function SpawnEnvironmentButtonPushed(app, event)
            SpawnGreenhouseEnvironment();
        end

        % Button pushed function: SpawnRobotButton
        function SpawnRobotButtonPushed(app, event)
            spawn(app);
            animate(app);
  
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            if getEstopStatus == false
                setEstopStatus(true);
                app.resumeStatus = false;
                app.StatusLamp.Color = 'r';
            else
                app.resumeStatus = true;
                app.StatusLamp.Color = 'g';
            end 
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if getEstopStatus == true && app.resumeStatus == true
                setEstopStatus(false);
            end
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            app.q(1) = value;
            animate(app);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            app.q(2) = deg2rad(value);
            animate(app);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            app.q(3) = deg2rad(value);
            animate(app);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            app.q(4) = deg2rad(value);
            animate(app);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            app.q(5) = deg2rad(value);
            animate(app);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
            app.q(6) = deg2rad(value);
            animate(app);
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
            value = app.q7Slider.Value;
            app.q(7) = deg2rad(value);
            animate(app);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(1) = endPos(1) + 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(1) = endPos(1) - 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(2) = endPos(2) + 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(2) = endPos(2) - 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(3) = endPos(3) + 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            endPos = transl(app.r.model.fkine(app.r.model.getpos));
            endPos(3) = endPos(3) - 0.05;
            app.q = app.r.model.ikcon(transl(endPos));
            animate(app);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.sim = Lab2ClassTestBackup();
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            main();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {510, 510};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EStopButton = uibutton(app.LeftPanel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.SpawnEnvironmentButton = uibutton(app.LeftPanel, 'push');
            app.SpawnEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @SpawnEnvironmentButtonPushed, true);
            app.SpawnEnvironmentButton.Text = 'Spawn Environment';
            app.SpawnRobotButton = uibutton(app.LeftPanel, 'push');
            app.SpawnRobotButton.ButtonPushedFcn = createCallbackFcn(app, @SpawnRobotButtonPushed, true);
            app.SpawnRobotButton.Text = 'Spawn Robot';
            app.StatusLampLabel = uilabel(app.LeftPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.LeftPanel);
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResumeButton = uibutton(app.LeftPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.TestButton = uibutton(app.LeftPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.RightPanel = uipanel(app.GridLayout);
            app.q1SliderLabel = uilabel(app.RightPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.RightPanel);
            app.q1Slider.Limits = [-0.8 -0.01];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.Value = -0.01;
            app.q2SliderLabel = uilabel(app.RightPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.RightPanel);
            app.q2Slider.Limits = [-360 360];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.RightPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.RightPanel);
            app.q3Slider.Limits = [-90 90];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.RightPanel);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.RightPanel);
            app.q5Slider.Limits = [-360 360];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.RightPanel);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.RightPanel);
            app.q4Slider.Limits = [-170 170];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.RightPanel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.RightPanel);
            app.q6Slider.Limits = [-360 360];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q7SliderLabel = uilabel(app.RightPanel);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.RightPanel);
            app.q7Slider.Limits = [-360 360];
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            app.YButton = uibutton(app.RightPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.RightPanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y-';
            app.XButton_2 = uibutton(app.RightPanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.XButton = uibutton(app.RightPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.ZButton = uibutton(app.RightPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z-';
            app.ZButton_2 = uibutton(app.RightPanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z+';
            app.TeachLabel = uilabel(app.RightPanel);
            app.TeachLabel.Text = 'Teach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end